classdef HistoLens_CompartmentSegmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CompartmentSegmentationUIFigure  matlab.ui.Figure
        Image_6                         matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Label_2                         matlab.ui.control.Label
        ViewingStyleButtonGroup         matlab.ui.container.ButtonGroup
        SidebySideButton                matlab.ui.control.ToggleButton
        OverlaidButton                  matlab.ui.control.ToggleButton
        NextSlideButton                 matlab.ui.control.Button
        Knob                            matlab.ui.control.DiscreteKnob
        ColorNormalizationMacenkoetalPanel  matlab.ui.container.Panel
        Image_2                         matlab.ui.control.Image
        ResetButton                     matlab.ui.control.Button
        MaxTable                        matlab.ui.control.Table
        MeanTable                       matlab.ui.control.Table
        SelectStain2Button              matlab.ui.control.Button
        SelectStain1Button              matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        ParametersPanel                 matlab.ui.container.Panel
        ColorSelectLabel                matlab.ui.control.Label
        ChannelDropdown                 matlab.ui.control.DropDown
        StainsTabGroup                  matlab.ui.container.TabGroup
        Stain1Tab                       matlab.ui.container.Tab
        Switch                          matlab.ui.control.ToggleSwitch
        SegmentationHierarchyLevelEditField  matlab.ui.control.NumericEditField
        SegmentationHierarchyLevelEditFieldLabel  matlab.ui.control.Label
        ColorChannelDropDown            matlab.ui.control.DropDown
        ColorChannelDropDownLabel       matlab.ui.control.Label
        MinimumSizeEditField            matlab.ui.control.NumericEditField
        MinimumSizeEditFieldLabel       matlab.ui.control.Label
        ThresholdValueEditField         matlab.ui.control.NumericEditField
        ThresholdValueEditFieldLabel    matlab.ui.control.Label
        Stain2Tab                       matlab.ui.container.Tab
        SegmentationHierarchyLevelEditField_2  matlab.ui.control.NumericEditField
        SegmentationHierarchyLevelEditField_2Label  matlab.ui.control.Label
        ColorChannelDropDown_2          matlab.ui.control.DropDown
        ColorChannelDropDown_2Label     matlab.ui.control.Label
        MinimumSizeEditField_2          matlab.ui.control.NumericEditField
        MinimumSizeEditField_2Label     matlab.ui.control.Label
        ThresholdValueEditField_2       matlab.ui.control.NumericEditField
        ThresholdValueEditField_2Label  matlab.ui.control.Label
        NucleiTab                       matlab.ui.container.Tab
        SplittingSlider                 matlab.ui.control.Slider
        SplittingSliderLabel            matlab.ui.control.Label
        SegmentationHierarchyLevelEditField_3  matlab.ui.control.NumericEditField
        SegmentationHierarchyLevelEditField_3Label  matlab.ui.control.Label
        ColorChannelDropDown_3          matlab.ui.control.DropDown
        ColorChannelDropDown_3Label     matlab.ui.control.Label
        MinimumSizeEditField_3          matlab.ui.control.NumericEditField
        MinimumSizeEditField_3Label     matlab.ui.control.Label
        ThresholdValueEditField_3       matlab.ui.control.NumericEditField
        ThresholdValueEditField_3Label  matlab.ui.control.Label
        NextStructureButton             matlab.ui.control.Button
        SegmentationMethodButtonGroup   matlab.ui.container.ButtonGroup
        Image                           matlab.ui.control.Image
        CustomSegmentationButton        matlab.ui.control.RadioButton
        ColorDeconvolutionButton        matlab.ui.control.RadioButton
        ColorspaceThresholdingButton    matlab.ui.control.RadioButton
        SelectStructureDropDown         matlab.ui.control.DropDown
        SelectStructureDropDownLabel    matlab.ui.control.Label
        SelectingSegmentationParametersLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_Comp                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        main_app
        %main_main_app
        
        HistoLens_Extract
        HistoLens_FeatureExtraction
        
    end
    
    properties (Access = public)
        
        MPP
        Baseline_MPP
        Slide_Path
        Slide_Names
        Current_Name
        Current_Img
        Norm_Img
        Current_Mask
        Seg_Params
        
        Channels
        Thresholds
        ThresholdDirs
        MinSizes
        Splittings

        Final_Seg_Params
        Current_Comp
        Seg_Script        
        Seg_Params_File       
        Structure_Names
        Stain_Names
        StainNorm_Params
        Current_ROIs
        Progress
        Slide_Idx
        Sample_Count
        structure_num
        Available_Structures
        Annotation_Format
        Structure_Idx_Name


        WSI_Formats = {'.svs','.tif','.tiff','.vms','.vmu','.ndpi','.scn',...
            '.mrxs','svslide','.bif'};

        Experiment_File
        Experiment_Struct
    end
    
    methods (Access = public)
        
        function View_SubCompartments(app)
            % View segmentation of sub-compartments based on selected
            % parameters

            if strcmp(app.ViewingStyleButtonGroup.SelectedObject.Text,'Overlaid')
                if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                    axes(app.UIAxes_Comp)
                    imshow(app.Current_Comp,'Parent',app.UIAxes_Comp)
                end
            else
                cla(app.UIAxes_Comp,'reset')
                imshowpair(app.Current_Img,app.Current_Comp,'montage','Parent',app.UIAxes_Comp)
            end
            
        end
        
        function ChannelValueChanged(app,src,event,i)
            % Updating channel to use for sub-compartment segmentation
            app.Channels(i) = event.Value;

            app.Seg_Params.(strcat('Stain',num2str(i))).Channel = app.Channels(i);


            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end  

            View_SubCompartments(app)

            
        end

        function ThresholdValueChanged(app,src,event,i)
            % Updating threshold value to use for sub-compartment
            % segmentation
            app.Thresholds(i) = event.Value;

            app.Seg_Params.(strcat('Stain',num2str(i))).Threshold = app.Thresholds(i);

            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end  

            View_SubCompartments(app)

        end

        function ThresholdDirectionChanged(app,src,event,i)
            % Updating threshold direction to use for sub-compartment
            % segmentation
            app.ThresholdDirs(i) = strcmp(event.Value,'above');

            app.Seg_Params.(strcat('Stain',num2str(i))).ThresholdDir = app.ThresholdDirs(i);

            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end  

            View_SubCompartments(app)

        end

        function MinimumSizeValueChanged(app,src,event,i)
            % Updating minimum size value to use for sub-compartment
            % segmentation
            app.MinSizes(i) = event.Value;

            app.Seg_Params.(strcat('Stain',num2str(i))).MinSize = app.MinSizes(i);

            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end  

            View_SubCompartments(app)

        end

        function SplittingValueChanged(app,src,event,i)
            % Updating splitting value to use for sub-compartment
            % segmentation
            app.Splittings(i) = event.Value;

            app.Seg_Params.(strcat('Stain',num2str(i))).Splitting = app.Splittings(i);

            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end  

            View_SubCompartments(app)

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            app.Slide_Path = main_app.Slide_Path;   
            app.Structure_Names = main_app.Structure_Names;
            app.Stain_Names = main_app.Stain_Names;
            app.Experiment_File = main_app.Experiment_File;
            app.Annotation_Format = main_app.Annotation_Format;
            app.Baseline_MPP = main_app.Baseline_MPP;
            
            slide_names = dir(app.Slide_Path);
            slide_names = {slide_names.name};
            slide_names = slide_names(~ismember(slide_names,{'.','..'}));
            slide_names = slide_names(contains(slide_names,app.WSI_Formats));
            app.Slide_Names = slide_names;

            % Getting the available structures for the current slide
            app.Available_Structures = Get_Available_Structures(app);

            % Initializing stains for segmentation
            app.Channels = ones(length(app.Stain_Names));
            app.Thresholds = ones(length(app.Stain_Names));
            app.ThresholdDirs = ones(length(app.Stain_Names));
            app.MinSizes = ones(length(app.Stain_Names));
            app.Splittings = zeros(length(app.Stain_Names));

            for st=1:length(app.Stain_Names)
                stain_field = strcat('Stain',num2str(st));
                app.Seg_Params.(stain_field).name = app.Stain_Names{st};
                
            end
            
            % Initializing stain tabs
            Add_Stain_Tabs(app)

            app.Slide_Idx = 1;
            app.SelectStructureDropDown.Items = app.Available_Structures{app.Slide_Idx};
            app.SelectStructureDropDown.Value = app.SelectStructureDropDown.Items{1};
            
            structure_idx_name = find(strcmp(app.SelectStructureDropDown.Value,app.Structure_Names{:,1}));
            structure_idx_name = strcat('Structure_',num2str(structure_idx_name));
            app.Structure_Idx_Name = structure_idx_name;
            app.Sample_Count.(app.Structure_Idx_Name) = 1;
            
            app.structure_num.Total = Get_Structure_Number(app);
            app.structure_num.Current = 1;

            Get_Default_Params(app)
            Extract_Rand_Img(app,app.structure_num.Current)
            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params, app.Norm_Img,app.Current_Mask);
            end
            
            app.Knob.Value = 'Original Image';
            imshow(app.Current_Img,'Parent',app.UIAxes_Comp)   

            app.Current_ROIs.H_ROIs = [];
            app.Current_ROIs.P_ROIs = [];
            
            % Empty patch that will be updated
            app.Progress.Patch = patch(app.UIAxes,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]);
            % Percent-complete text that will be updated
            app.Progress.Text = text(app.UIAxes,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            % Updating slide progress bar with current slide name
            title(app.UIAxes, strcat('On Slide:',' ',app.Current_Name,' ','Progress:',' ',num2str(app.Sample_Count.(app.Structure_Idx_Name))))

            % Populating Stain Means and Maxs
            app.MeanTable.Data = zeros(3,2);
            app.MaxTable.Data = zeros(3,2);

        end

        % Selection changed function: SegmentationMethodButtonGroup
        function SegmentationMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.SegmentationMethodButtonGroup.SelectedObject;
            string_vals = {app.SegmentationMethodButtonGroup.Buttons.Text};
            butt_idx = find(strcmp(selectedButton.Text,string_vals));
            
            % Removing path to custom segmentation script from search path if necessary
            if ismember('CustomPath',fieldnames(app.Seg_Params))
                rmpath(app.Seg_Params.CustomPath)
            end
            
            app.Seg_Params = [];
            % Getting the default segmentation parameters for the selected
            % method
            Get_Default_Params(app)
            
            % Colorspace segmentation
            if butt_idx==1

                app.SelectStain1Button.Enable = 'on';
                app.SelectStain2Button.Enable = 'on';
                app.MeanTable.Enable = 'on';
                app.MaxTable.Enable = 'on';

                app.ParametersPanel.Enable = 'on';
                app.ParametersPanel.Visible = 'on';
                
                app.ColorDeconvolutionParametersPanel.Enable = 'off';
                app.ColorDeconvolutionParametersPanel.Visible = 'off';

                app.CustomSegmentationMasksPanel.Enable = 'off';
                app.CustomSegmentationMasksPanel.Visible = 'off';

                if isempty(app.Norm_Img)
                    app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
                else
                    app.Current_Comp = Comp_Seg(app.Seg_Params, app.Norm_Img,app.Current_Mask);
                end
            
                if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                    axes(app.UIAxes_Comp)
                    imshow(app.Current_Comp,'Parent',app.UIAxes_Comp)
                end   

            end
            
            % Color deconvolution segmentation
            if butt_idx==2

                % Disabling normalization because it doesn't make sense for
                % color deconvolution
                app.SelectStain1Button.Enable = 'off';
                app.SelectStain2Button.Enable = 'off';
                app.MeanTable.Enable = 'off';
                app.MeanTable.Data = [];
                app.MaxTable.Enable = 'off';
                app.MaxTable.Data = [];

                app.StainNorm_Params = [];

                app.ColorDeconvolutionParametersPanel.Enable = 'on';
                app.ColorDeconvolutionParametersPanel.Visible = 'on';
                
                app.ParametersPanel.Enable = 'off';
                app.ParametersPanel.Visible = 'off';

                app.CustomSegmentationMasksPanel.Enable = 'off';
                app.CustomSegmentationMasksPanel.Visible = 'off';

                if isempty(app.Norm_Img)
                    app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
                else
                    app.Current_Comp = Comp_Seg(app.Seg_Params, app.Norm_Img,app.Current_Mask);
                end

                if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                    axes(app.UIAxes_Comp)
                    imshow(app.Current_Comp,'Parent',app.UIAxes_Comp)
                end   

            end
            
            % Custom segmentations folder
            if butt_idx == 3
                app.CustomSegmentationMasksPanel.Enable = 'on';
                app.CustomSegmentationMasksPanel.Visible = 'on';
                
                app.ParametersPanel.Enable = 'off';
                app.ParametersPanel.Visible = 'off';

                
            end            

         
        end

        % Button pushed function: NextStructureButton
        function NextStructureButtonPushed(app, event)
            
            app.Current_ROIs.H_ROIs = [];
            app.Current_ROIs.P_ROIs = [];

            %current_structure = app.SelectStructureDropDown.Value;
            
            if app.structure_num.Current+1<=app.structure_num.Total
                app.structure_num.Current = app.structure_num.Current+1;
            else
                app.structure_num.Current = 1;
            end

            app.Sample_Count.(app.Structure_Idx_Name) = app.structure_num.Current;

            % Getting the structure index
            Extract_Rand_Img(app,app.structure_num.Current)
            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end

            
            title(app.UIAxes, strcat('On Slide:',' ',app.Current_Name,' ','Progress',' ',num2str(app.Sample_Count.(app.Structure_Idx_Name))))
            View_SubCompartments(app)

            
        end

        % Button pushed function: NextSlideButton
        function NextSlideButtonPushed(app, event)
            
            app.Current_ROIs.H_ROIs = [];
            app.Current_ROIs.P_ROIs = [];
            
            prev_slide = app.Slide_Names{app.Slide_Idx};
            slide_idx_name = strcat('Slide_Idx_',num2str(app.Slide_Idx));

            app.Final_Seg_Params.(slide_idx_name).CompartmentSegmentation = app.Seg_Params;
            app.Final_Seg_Params.(slide_idx_name).SlideName = prev_slide;

            if app.Slide_Idx+1<= length(app.Slide_Names)
                app.Slide_Idx = app.Slide_Idx+1;
                
            else
                app.Slide_Idx = 1;
            end

            app.SelectStructureDropDown.Items = app.Available_Structures{app.Slide_Idx};

            app.structure_num.Total = Get_Structure_Number(app);
            app.structure_num.Current = 1;
            
            % Updating Progress Bar
            axes(app.UIAxes)
            app.Progress.Patch.XData = [0 ((app.Slide_Idx-1)/length(app.Slide_Names)) ((app.Slide_Idx-1)/length(app.Slide_Names)) 0];
            app.Progress.Text.String = sprintf('%.0f%%',round(100*((app.Slide_Idx-1)/length(app.Slide_Names))));
            app.Progress.Text.Position = [1 1 0];
            drawnow

            % Loading previous segmentation parameters if those are
            % available
            Get_Current_Seg_Params(app,app.Slide_Idx)
            
            app.Sample_Count.(app.Structure_Idx_Name) = 1;

            Extract_Rand_Img(app,app.structure_num.Current)
            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end            
            title(app.UIAxes, strcat('On Slide:',' ',app.Current_Name,' ','Progress',' ',num2str(app.Sample_Count.(app.Structure_Idx_Name))))
            View_SubCompartments(app)    


        end

        % Callback function
        function SelectFolderButtonPushed(app, event)
            
            % Get folder containing compartment segmentations to use
            segmentation_paths = uigetdir(app.Slide_Path,'Select folder containing compartment segmentations for each image');
            
            if segmentation_paths ~= 0
                app.FoldernameLabel.Text = segmentation_paths;
    
                % Adding to app.Seg_Params
                app.Seg_Params.Path = segmentation_paths;
    
                if isempty(app.Norm_Img)
                    app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
                else
                    app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
                end  

                View_SubCompartments(app)

            end
        end

        % Close request function: CompartmentSegmentationUIFigure
        function CompartmentSegmentationUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: ChannelDropdown
        function ChannelDropdownValueChanged(app, event)
            value = app.ChannelDropdown.Value;
            
            % Colorspace to use for segmentation
            app.Seg_Params.Colorspace = value;
                    
            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end  

            View_SubCompartments(app)

        end

        % Value changed function: ColorChannelDropDown
        function ColorChannelDropDownValueChanged(app, event)
            value = app.ColorChannelDropDown.Value;
            channel_code = strsplit(value,' ');
            channel_code = channel_code{end};
            channel_code = str2num(channel_code);
            
            % Luminal colorspace channel
            app.Seg_Params.Luminal.Channel = channel_code;
                        
            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end  
            
            View_SubCompartments(app)

        end

        % Value changed function: ThresholdValueEditField
        function ThresholdValueEditFieldValueChanged(app, event)
            value = app.ThresholdValueEditField.Value;
            
            % Luminal Colorspace threshold
            app.Seg_Params.Luminal.Threshold = value;
            
            % Generate updated compartment segmentation
            
            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end  
            
            View_SubCompartments(app)

        end

        % Value changed function: MinimumSizeEditField
        function MinimumSizeEditFieldValueChanged(app, event)
            value = app.MinimumSizeEditField.Value;
            
            % Luminal Colorspace minimum size
            app.Seg_Params.Luminal.MinSize = value;
            
            % Generate updated compartment segmentation
            
            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end  
            
            View_SubCompartments(app)

        end

        % Value changed function: SegmentationHierarchyLevelEditField
        function SegmentationHierarchyLevelEditFieldValueChanged(app, event)
            value = app.SegmentationHierarchyLevelEditField.Value;
            
            % Current order will have each compartment's current order
            current_order = [app.Seg_Params.PAS.Order,...
                app.Seg_Params.Luminal.Order,...
                app.Seg_Params.Nuclei.Order];
            seg_params = [app.Seg_Params.PAS,...
                app.Seg_Params.Luminal,...
                app.Seg_Params.Nuclei];
            
            % edit fields for segmentation hierarchy
            edit_fields = [app.SegmentationHierarchyLevelEditField,...
                app.SegmentationHierarchyLevelEditField_2,...
                app.SegmentationHierarchyLevelEditField_3];
            
            % Luminal Colorspace segmentation hierarchy
            prev_order = current_order(1);
            
            % if new position is different by 1
            if abs(prev_order-value)==1
                rep_idx = find(current_order==value);
                seg_params(rep_idx).Order = prev_order;
                seg_params(1).Order = value;
                
                edit_fields(rep_idx).Value = prev_order;
            else
                % if new position is different by 2
                if prev_order<value
                    % moving from position 1 to position 3
                    seg_params(find(current_order==2)).Order = 1;
                    seg_params(find(current_order==3)).Order = 2;
                    seg_params(1).Order = value;
                    
                    edit_fields(find(current_order==2)).Value = 1;
                    edit_fields(find(current_order==3)).Value = 2;
                    
                else
                    % moving from position 3 to position 1
                    seg_params(find(current_order==2)).Order = 3;
                    seg_params(find(current_order==1)).Order = 2;
                    seg_params(1).Order = value;                 
                    
                    edit_fields(find(current_order==2)).Value = 3;
                    edit_fields(find(current_order==3)).Value = 2;
                end                
            end

            app.Seg_Params.PAS = seg_params(1);
            app.Seg_Params.Luminal = seg_params(2);
            app.Seg_Params.Nuclei = seg_params(3);
            
            % Updating enabled status within tab group
            Enable_Fields(app,event)  
            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end  
            
            View_SubCompartments(app)

        end

        % Callback function
        function StainTypeDropDownValueChanged(app, event)
            value = app.StainTypeDropDown.Value;
            stain_code = strsplit(value,'(');
            stain_code = stain_code{end};
            stain_code = strrep(stain_code,')','');
            % Changing stain type for color deconvolution
            app.Seg_Params.Stain = stain_code;

            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end  
            
            View_SubCompartments(app)


        end

        % Callback function
        function StainChannelDropDownValueChanged(app, event)
            value = app.StainChannelDropDown.Value;            
            channel_code = strsplit(value,' ');
            channel_code = channel_code{end};
            channel_code = str2num(channel_code);
            
            % Luminal color deconvolution channel
            app.Seg_Params.Luminal.Channel = channel_code;
            
            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end  
            
            View_SubCompartments(app)

        end

        % Button pushed function: SelectStain1Button
        function SelectStain1ButtonPushed(app, event)
            
            % Selecting region in current image corresponding to
            % Hematoxylin stain, storing average OD values

            %H_roi = images.roi.Freehand(app.UIAxes_Comp,'Smoothing',0,'FaceAlpha',0,...
            %    'Label','H ROI');
            H_point_roi = images.roi.Point(app.UIAxes_Comp);
            H_point_roi.draw()

            rect_position = [H_point_roi.Position(1)-1,H_point_roi.Position(2)-1,2,2];
            
            % Adding the rect_position to the list of current ROIs, used to
            % determine the OD values used to normalize the stain in the
            % image
            if ~isempty(app.Current_ROIs.H_ROIs)
                app.Current_ROIs.H_ROIs = [app.Current_ROIs.H_ROIs;rect_position];
            else
                app.Current_ROIs.H_ROIs = [rect_position];
            end

            % New Rectangular ROI with name 
            H_roi = images.roi.Rectangle(app.UIAxes_Comp,'Position',rect_position,...
                'FaceAlpha',0,'Label',strcat('H ROI_',num2str(size(app.Current_ROIs.H_ROIs,1))));
            
            delete(H_point_roi)
            addlistener(H_roi,'ROIMoved',@(src,event)ROI_update(src,event,app));
            addlistener(H_roi,'DeletingROI',@(src,event)ROI_update(src,event,app));
            addlistener(H_roi,'DrawingFinished',@(src,event)ROI_update(src,event,app));

            Update_Normalization_Params(app)


        end

        % Button pushed function: SelectStain2Button
        function SelectStain2ButtonPushed(app, event)
            % Selecting region in current image corresponding to
            % Eosin stain, storing average OD values

            P_point_roi = images.roi.Point(app.UIAxes_Comp);
            P_point_roi.draw()

            rect_position = [P_point_roi.Position(1)-1,P_point_roi.Position(2)-1,2,2];

            % Adding the rect_position to the list of current ROIs, used to
            % determine the OD values used to normalize the stain in the
            % image
            if ~isempty(app.Current_ROIs.P_ROIs)
                app.Current_ROIs.P_ROIs = [app.Current_ROIs.P_ROIs;rect_position];
            else
                app.Current_ROIs.P_ROIs = [rect_position];
            end

            P_roi = images.roi.Rectangle(app.UIAxes_Comp,'Position',rect_position,...
                'FaceAlpha',0,'Label',strcat('P ROI_',num2str(size(app.Current_ROIs.P_ROIs,1))));
            
            delete(P_point_roi)
            addlistener(P_roi,'ROIMoved',@(src,event)ROI_update(src,event,app));
            addlistener(P_roi,'DeletingROI',@(src,event)ROI_update(src,event,app));
            addlistener(P_roi,'DrawingFinished',@(src,event)ROI_update(src,event,app));

            Update_Normalization_Params(app)


        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            
            if strcmp(value,'Original Image')
                imshow(app.Current_Img,'Parent',app.UIAxes_Comp)
            elseif strcmp(value,'Sub-Compartment Mask')
                imshow(app.Current_Comp,'Parent',app.UIAxes_Comp)
            else
                imshow(app.Norm_Img,'Parent',app.UIAxes_Comp)
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            app.MeanTable.Data = zeros(3,2);
            app.MaxTable.Data = zeros(3,2);

            app.StainNorm_Params.Means = zeros(3,2);
            app.StainNorm_Params.Maxs = zeros(3,2);

            app.Current_ROIs.H_ROIs = [];
            app.Current_ROIs.P_ROIs = [];

        end

        % Cell edit callback: MeanTable
        function MeanTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            app.StainNorm_Params.Means(indices(1),indices(2)) = newData;


        end

        % Cell edit callback: MaxTable
        function MaxTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            app.StainNorm_Params.Maxs(indices(1),indices(2)) = newData;

        end

        % Value changed function: SelectStructureDropDown
        function SelectStructureDropDownValueChanged(app, event)
            value = app.SelectStructureDropDown.Value;

            structure_idx_name = find(strcmp(value,app.Structure_Names{:,1}));
            structure_idx_name = strcat('Structure_',num2str(structure_idx_name));
            app.Structure_Idx_Name = structure_idx_name;
            
            app.structure_num.Total = Get_Structure_Number(app);
            
            if ~ismember(app.Structure_Idx_Name,fieldnames(app.Sample_Count))
                app.Sample_Count.(app.Structure_Idx_Name) = 1;
                app.structure_num.Current = 1;
            else
                app.structure_num.Current = app.Sample_Count.(app.Structure_Idx_Name);
            end

            Extract_Rand_Img(app,app.structure_num.Current)

            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end

            title(app.UIAxes, strcat(['On Slide:',' ',app.Current_Name,' ','Progress',' ',num2str(app.Sample_Count.(app.Structure_Idx_Name))]))

            View_SubCompartments(app)

        end

        % Value changed function: SplittingSlider
        function SplittingSliderValueChanged(app, event)
            value = app.SplittingSlider.Value;
            
            app.Seg_Params.Nuclei.Splitting = value/100;
            
            % Generate updated compartment segmentation
            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end

            View_SubCompartments(app)

        end

        % Callback function
        function SplittingSlider_2ValueChanged(app, event)
            value = app.SplittingSlider_2.Value;
            
            app.Seg_Params.Nuclei.Splitting = value;

            % Generate updated compartment segmentation
            if isempty(app.Norm_Img)
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Current_Img,app.Current_Mask);
            else
                app.Current_Comp = Comp_Seg(app.Seg_Params,app.Norm_Img,app.Current_Mask);
            end
            
            View_SubCompartments(app)

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            current_structure = app.SelectStructureDropDown.Value;
            prev_slide = app.Slide_Names{app.Slide_Idx};
            slide_idx_name = strcat('Slide_Idx_',num2str(app.Slide_Idx));

            % Add something here to apply same segmentation params for
            % every slide remaining if there are still slides remaining

            app.Final_Seg_Params.(slide_idx_name).CompartmentSegmentation = app.Seg_Params;
            app.Final_Seg_Params.(slide_idx_name).SlideName = prev_slide;

            % Checking if there are segmentation parameters for each slide
            if length(fieldnames(app.Final_Seg_Params))~=length(app.Slide_Names)
                display('Projecting segmentation parameters')
                Project_Segmentation_Parameters(app)
            else
                display('All slides covered')
            end
                
            app.Seg_Params = app.Final_Seg_Params;
            % Adding stain normalization values to app.Seg_Parms
            if ~isempty(app.StainNorm_Params)
                app.Seg_Params.StainNormalization = app.StainNorm_Params;
            end
            
            % Update main_app and continue to feature extraction, close
            % compartment segmentation window
            app.main_app.Seg_Params = app.Seg_Params;

            Get_Seg_Params(app.main_app,app)
            %Format_Experiment_File(app,app.main_app)
            Write_Experiment_File(app)
            Get_Experiment_Struct(app.main_app,app)
            
            % Close app
            delete(app)
            
        end

        % Selection changed function: ViewingStyleButtonGroup
        function ViewingStyleButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewingStyleButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Overlaid')
                app.Knob.Enable = 'on';
                app.Knob.Value = 'Original Image';
                axes(app.UIAxes_Comp)
                imshow(app.Current_Img,'Parent',app.UIAxes_Comp)

            else
                app.Knob.Enable = 'off';
                cla(app.UIAxes_Comp,'reset')
                imshowpair(app.Current_Img,app.Current_Comp,'montage','Parent',app.UIAxes_Comp)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CompartmentSegmentationUIFigure = uifigure('Visible', 'off');
            app.CompartmentSegmentationUIFigure.Name = 'Compartment Segmentation';
            app.CompartmentSegmentationUIFigure.Icon = 'Logo.png';
            app.CompartmentSegmentationUIFigure.CloseRequestFcn = createCallbackFcn(app, @CompartmentSegmentationUIFigureCloseRequest, true);
            app.UIAxes_Comp = uiaxes(app.CompartmentSegmentationUIFigure);
            zlabel(app.UIAxes_Comp, 'Z')
            app.UIAxes_Comp.XTick = [];
            app.UIAxes_Comp.YTick = [];
            app.UIAxes = uiaxes(app.CompartmentSegmentationUIFigure);
            title(app.UIAxes, 'On Slide: Progress:')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.SelectingSegmentationParametersLabel = uilabel(app.CompartmentSegmentationUIFigure);
            app.SelectingSegmentationParametersLabel.Text = 'Selecting Segmentation Parameters';
            app.SelectStructureDropDownLabel = uilabel(app.CompartmentSegmentationUIFigure);
            app.SelectStructureDropDownLabel.Text = 'Select Structure';
            app.SelectStructureDropDown = uidropdown(app.CompartmentSegmentationUIFigure);
            app.SelectStructureDropDown.Items = {};
            app.SelectStructureDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectStructureDropDownValueChanged, true);
            app.SelectStructureDropDown.Placeholder = 'Select One';
            app.SelectStructureDropDown.Value = {};
            app.SegmentationMethodButtonGroup = uibuttongroup(app.CompartmentSegmentationUIFigure);
            app.SegmentationMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SegmentationMethodButtonGroupSelectionChanged, true);
            app.SegmentationMethodButtonGroup.Title = 'Segmentation Method';
            app.ColorspaceThresholdingButton = uiradiobutton(app.SegmentationMethodButtonGroup);
            app.ColorspaceThresholdingButton.Text = 'Colorspace Thresholding';
            app.ColorspaceThresholdingButton.Value = true;
            app.ColorDeconvolutionButton = uiradiobutton(app.SegmentationMethodButtonGroup);
            app.ColorDeconvolutionButton.Text = 'Color Deconvolution';
            app.CustomSegmentationButton = uiradiobutton(app.SegmentationMethodButtonGroup);
            app.CustomSegmentationButton.Text = 'Custom Segmentation ';
            app.Image = uiimage(app.SegmentationMethodButtonGroup);
            app.Image.ImageSource = 'info_symbol.png';
            app.NextStructureButton = uibutton(app.CompartmentSegmentationUIFigure, 'push');
            app.NextStructureButton.ButtonPushedFcn = createCallbackFcn(app, @NextStructureButtonPushed, true);
            app.NextStructureButton.Text = 'Next Structure';
            app.ParametersPanel = uipanel(app.CompartmentSegmentationUIFigure);
            app.ParametersPanel.Title = 'Colorspace Parameters';
            app.StainsTabGroup = uitabgroup(app.ParametersPanel);
            app.Stain1Tab = uitab(app.StainsTabGroup);
            app.Stain1Tab.Title = 'Stain 1';
            app.ThresholdValueEditFieldLabel = uilabel(app.Stain1Tab);
            app.ThresholdValueEditFieldLabel.Text = 'Threshold Value';
            app.ThresholdValueEditField = uieditfield(app.Stain1Tab, 'numeric');
            app.ThresholdValueEditField.Limits = [0 255];
            app.ThresholdValueEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueEditFieldValueChanged, true);
            app.ThresholdValueEditField.Enable = 'off';
            app.MinimumSizeEditFieldLabel = uilabel(app.Stain1Tab);
            app.MinimumSizeEditFieldLabel.Text = 'Minimum Size';
            app.MinimumSizeEditField = uieditfield(app.Stain1Tab, 'numeric');
            app.MinimumSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumSizeEditFieldValueChanged, true);
            app.MinimumSizeEditField.Enable = 'off';
            app.ColorChannelDropDownLabel = uilabel(app.Stain1Tab);
            app.ColorChannelDropDownLabel.Enable = 'off';
            app.ColorChannelDropDownLabel.Text = 'Color Channel';
            app.ColorChannelDropDown = uidropdown(app.Stain1Tab);
            app.ColorChannelDropDown.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.ColorChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorChannelDropDownValueChanged, true);
            app.ColorChannelDropDown.Enable = 'off';
            app.ColorChannelDropDown.Value = 'Channel 1';
            app.SegmentationHierarchyLevelEditFieldLabel = uilabel(app.Stain1Tab);
            app.SegmentationHierarchyLevelEditFieldLabel.Text = 'Segmentation Hierarchy Level';
            app.SegmentationHierarchyLevelEditField = uieditfield(app.Stain1Tab, 'numeric');
            app.SegmentationHierarchyLevelEditField.Limits = [1 3];
            app.SegmentationHierarchyLevelEditField.RoundFractionalValues = 'on';
            app.SegmentationHierarchyLevelEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentationHierarchyLevelEditFieldValueChanged, true);
            app.SegmentationHierarchyLevelEditField.Value = 3;
            app.Switch = uiswitch(app.Stain1Tab, 'toggle');
            app.Switch.Items = {'', ''};
            app.Switch.Value = '';
            app.Stain2Tab = uitab(app.StainsTabGroup);
            app.Stain2Tab.Title = 'Stain 2';
            app.ThresholdValueEditField_2Label = uilabel(app.Stain2Tab);
            app.ThresholdValueEditField_2Label.Text = 'Threshold Value';
            app.ThresholdValueEditField_2 = uieditfield(app.Stain2Tab, 'numeric');
            app.ThresholdValueEditField_2.Limits = [0 255];
            app.ThresholdValueEditField_2.Value = 50;
            app.MinimumSizeEditField_2Label = uilabel(app.Stain2Tab);
            app.MinimumSizeEditField_2Label.Text = 'Minimum Size';
            app.MinimumSizeEditField_2 = uieditfield(app.Stain2Tab, 'numeric');
            app.MinimumSizeEditField_2.Value = 10;
            app.ColorChannelDropDown_2Label = uilabel(app.Stain2Tab);
            app.ColorChannelDropDown_2Label.Text = 'Color Channel';
            app.ColorChannelDropDown_2 = uidropdown(app.Stain2Tab);
            app.ColorChannelDropDown_2.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.ColorChannelDropDown_2.Value = 'Channel 2';
            app.SegmentationHierarchyLevelEditField_2Label = uilabel(app.Stain2Tab);
            app.SegmentationHierarchyLevelEditField_2Label.Text = 'Segmentation Hierarchy Level';
            app.SegmentationHierarchyLevelEditField_2 = uieditfield(app.Stain2Tab, 'numeric');
            app.SegmentationHierarchyLevelEditField_2.Limits = [1 3];
            app.SegmentationHierarchyLevelEditField_2.RoundFractionalValues = 'on';
            app.SegmentationHierarchyLevelEditField_2.Value = 2;
            app.NucleiTab = uitab(app.StainsTabGroup);
            app.NucleiTab.Title = 'Nuclei';
            app.ThresholdValueEditField_3Label = uilabel(app.NucleiTab);
            app.ThresholdValueEditField_3Label.Text = 'Threshold Value';
            app.ThresholdValueEditField_3 = uieditfield(app.NucleiTab, 'numeric');
            app.ThresholdValueEditField_3.Limits = [0 255];
            app.ThresholdValueEditField_3.Value = 125;
            app.MinimumSizeEditField_3Label = uilabel(app.NucleiTab);
            app.MinimumSizeEditField_3Label.Text = 'Minimum Size';
            app.MinimumSizeEditField_3 = uieditfield(app.NucleiTab, 'numeric');
            app.MinimumSizeEditField_3.Value = 30;
            app.ColorChannelDropDown_3Label = uilabel(app.NucleiTab);
            app.ColorChannelDropDown_3Label.Text = 'Color Channel';
            app.ColorChannelDropDown_3 = uidropdown(app.NucleiTab);
            app.ColorChannelDropDown_3.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.ColorChannelDropDown_3.Value = 'Channel 2';
            app.SegmentationHierarchyLevelEditField_3Label = uilabel(app.NucleiTab);
            app.SegmentationHierarchyLevelEditField_3Label.Text = 'Segmentation Hierarchy Level';
            app.SegmentationHierarchyLevelEditField_3 = uieditfield(app.NucleiTab, 'numeric');
            app.SegmentationHierarchyLevelEditField_3.Limits = [1 3];
            app.SegmentationHierarchyLevelEditField_3.RoundFractionalValues = 'on';
            app.SegmentationHierarchyLevelEditField_3.Value = 1;
            app.SplittingSliderLabel = uilabel(app.NucleiTab);
            app.SplittingSliderLabel.Text = 'Splitting';
            app.SplittingSlider = uislider(app.NucleiTab);
            app.SplittingSlider.ValueChangedFcn = createCallbackFcn(app, @SplittingSliderValueChanged, true);
            app.SplittingSlider.Value = 5;
            app.ChannelDropdown = uidropdown(app.ParametersPanel);
            app.ChannelDropdown.Items = {'RGB (Red, Green, Blue)', 'HSV (Hue, Saturation, Value)', 'LAB '};
            app.ChannelDropdown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropdownValueChanged, true);
            app.ChannelDropdown.Value = 'HSV (Hue, Saturation, Value)';
            app.ColorSelectLabel = uilabel(app.ParametersPanel);
            app.ColorSelectLabel.Text = 'Color Select';
            app.DoneButton = uibutton(app.CompartmentSegmentationUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ColorNormalizationMacenkoetalPanel = uipanel(app.CompartmentSegmentationUIFigure);
            app.ColorNormalizationMacenkoetalPanel.Title = 'Color Normalization (Macenko, et. al)';
            app.SelectStain1Button = uibutton(app.ColorNormalizationMacenkoetalPanel, 'push');
            app.SelectStain1Button.ButtonPushedFcn = createCallbackFcn(app, @SelectStain1ButtonPushed, true);
            app.SelectStain1Button.Text = 'Select Stain 1';
            app.SelectStain2Button = uibutton(app.ColorNormalizationMacenkoetalPanel, 'push');
            app.SelectStain2Button.ButtonPushedFcn = createCallbackFcn(app, @SelectStain2ButtonPushed, true);
            app.SelectStain2Button.Text = 'Select Stain 2';
            app.MeanTable = uitable(app.ColorNormalizationMacenkoetalPanel);
            app.MeanTable.ColumnName = {'Stain 1 Means'; 'Stain 2 Means'};
            app.MeanTable.RowName = {};
            app.MeanTable.ColumnEditable = true;
            app.MeanTable.CellEditCallback = createCallbackFcn(app, @MeanTableCellEdit, true);
            app.MaxTable = uitable(app.ColorNormalizationMacenkoetalPanel);
            app.MaxTable.ColumnName = {'Stain 1 Max'; 'Stain 2 Max'};
            app.MaxTable.RowName = {};
            app.MaxTable.ColumnEditable = true;
            app.MaxTable.CellEditCallback = createCallbackFcn(app, @MaxTableCellEdit, true);
            app.ResetButton = uibutton(app.ColorNormalizationMacenkoetalPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image_2 = uiimage(app.ColorNormalizationMacenkoetalPanel);
            app.Image_2.ImageSource = 'info_symbol.png';
            app.Knob = uiknob(app.CompartmentSegmentationUIFigure, 'discrete');
            app.Knob.Items = {'Original Image', 'Color Normalized Image', 'Sub-Compartment Mask'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'Original Image';
            app.NextSlideButton = uibutton(app.CompartmentSegmentationUIFigure, 'push');
            app.NextSlideButton.ButtonPushedFcn = createCallbackFcn(app, @NextSlideButtonPushed, true);
            app.NextSlideButton.Text = 'Next Slide';
            app.ViewingStyleButtonGroup = uibuttongroup(app.CompartmentSegmentationUIFigure);
            app.ViewingStyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewingStyleButtonGroupSelectionChanged, true);
            app.ViewingStyleButtonGroup.Title = 'Viewing Style';
            app.OverlaidButton = uitogglebutton(app.ViewingStyleButtonGroup);
            app.OverlaidButton.Text = 'Overlaid';
            app.OverlaidButton.Value = true;
            app.SidebySideButton = uitogglebutton(app.ViewingStyleButtonGroup);
            app.SidebySideButton.Text = 'Side-by-Side';
            app.Label_2 = uilabel(app.CompartmentSegmentationUIFigure);
            app.Label_2.Text = 'This window is where you can specify parameters for sub-compartment segmentation. ';
            app.Image_3 = uiimage(app.CompartmentSegmentationUIFigure);
            app.Image_3.ImageSource = 'info_symbol.png';
            app.Image_4 = uiimage(app.CompartmentSegmentationUIFigure);
            app.Image_4.ImageSource = 'info_symbol.png';
            app.Image_5 = uiimage(app.CompartmentSegmentationUIFigure);
            app.Image_5.ImageSource = 'info_symbol.png';
            app.Image_6 = uiimage(app.CompartmentSegmentationUIFigure);
            app.Image_6.ImageSource = 'info_symbol.png';
            % Show the figure after all components are created
            app.CompartmentSegmentationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_CompartmentSegmentation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CompartmentSegmentationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CompartmentSegmentationUIFigure)
        end
    end
end